classdef BlackbodyRadiationCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        BlackbodytempKEditFieldLabel  matlab.ui.control.Label
        BlackbodytempKelven           matlab.ui.control.NumericEditField
        EmissivityEditFieldLabel      matlab.ui.control.Label
        Emissivity                    matlab.ui.control.NumericEditField
        HighedgeumEditFieldLabel      matlab.ui.control.Label
        Highedge                      matlab.ui.control.NumericEditField
        CulculateButton               matlab.ui.control.Button
        DeviceSize                    matlab.ui.control.NumericEditField
        BlackbodytempEditFieldLabel   matlab.ui.control.Label
        BlackbodytempCelcius          matlab.ui.control.NumericEditField
        LowedgeumEditFieldLabel       matlab.ui.control.Label
        Lowedge                       matlab.ui.control.NumericEditField
        outputPower                   matlab.ui.control.NumericEditField
        outputCPS                     matlab.ui.control.NumericEditField
        HighedgeumEditFieldLabel_2    matlab.ui.control.Label
        HighedgeumEditFieldLabel_3    matlab.ui.control.Label
        Unit                          matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        PrincipalInterestUIAxes       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {350, 350};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: CulculateButton
        function CulculateButtonPushed(app, event)
            
            % Update custom value
            cla(app.PrincipalInterestUIAxes) 
            step = 0.01;
            dist = 0.05;
            NA = 0.02;
            sr = (NA/2)^2*pi/(4*pi*dist^2);
            size = app.DeviceSize.Value*1e-12;
            wavelength = 0.0:step:25;
            Temp = app.BlackbodytempKelven.Value;
            emiss = app.Emissivity.Value/100;
            bandedge1 = find(wavelength == app.Lowedge.Value);
            bandedge2 = find(wavelength == app.Highedge.Value);
            c1=3.7414*10^8;%W⋅m −2 ⋅μm 4
            c2=1.43879*10^4;%μm⋅K
            emission = emiss*(c1./(wavelength.^5)).*(1./(exp(c2./wavelength./Temp)-1));
            e_const = 1.60217733e-19;
            h_const = 6.626070150e-34;
            c0 = 299792458;
            cps = emission./(c0./(wavelength*1e-6)*h_const);
            sum_power = sum(emission(bandedge1:bandedge2)*step);
            sum_cps = sum(cps(bandedge1:bandedge2)*step);
            
            % Plot the principal and interest
            hold(app.PrincipalInterestUIAxes,"on");
            plot(app.PrincipalInterestUIAxes,wavelength, emission,'-','LineWidth',3);
            area(app.PrincipalInterestUIAxes,wavelength(bandedge1:bandedge2), emission(bandedge1:bandedge2),'FaceAlpha',.5,'EdgeAlpha',0);
            hold(app.PrincipalInterestUIAxes,"off");
            xlim(app.PrincipalInterestUIAxes,[0, 25]);
            
            app.outputPower.Value = sum_power*size;
            app.outputCPS.Value = sum_cps*size;
            %             plot(app.PrincipalInterestUIAxes,(1:nper)',principal, ...
            %                 (1:nper)',interest);
            %             legend(app.PrincipalInterestUIAxes,{'Principal','Interest'},'Location','Best')
            %             xlim(app.PrincipalInterestUIAxes,[0 nper]);
            
        end

        % Value changed function: BlackbodytempCelcius
        function BlackbodytempCelciusValueChanged(app, event)
            value = app.BlackbodytempCelcius.Value;
            app.BlackbodytempKelven.Value = value+273.15;
        end

        % Value changed function: BlackbodytempKelven
        function BlackbodytempKelvenValueChanged(app, event)
            value = app.BlackbodytempKelven.Value;
            app.BlackbodytempCelcius.Value = value-273.15;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BlackbodytempKEditFieldLabel = uilabel(app.LeftPanel);
            app.BlackbodytempKEditFieldLabel.Text = 'Black body temp (K)';
            app.BlackbodytempKelven = uieditfield(app.LeftPanel, 'numeric');
            app.BlackbodytempKelven.Limits = [0 10000];
            app.BlackbodytempKelven.ValueDisplayFormat = '%8.f';
            app.BlackbodytempKelven.ValueChangedFcn = createCallbackFcn(app, @BlackbodytempKelvenValueChanged, true);
            app.BlackbodytempKelven.Value = 1173.15;
            app.EmissivityEditFieldLabel = uilabel(app.LeftPanel);
            app.EmissivityEditFieldLabel.Text = 'Emissivity %';
            app.Emissivity = uieditfield(app.LeftPanel, 'numeric');
            app.Emissivity.Limits = [0.001 100];
            app.Emissivity.ValueDisplayFormat = '%8.f';
            app.Emissivity.Value = 100;
            app.HighedgeumEditFieldLabel = uilabel(app.LeftPanel);
            app.HighedgeumEditFieldLabel.Text = 'High edge (um)';
            app.Highedge = uieditfield(app.LeftPanel, 'numeric');
            app.Highedge.Limits = [0.1 25];
            app.Highedge.ValueDisplayFormat = '%8.f';
            app.Highedge.Value = 12;
            app.CulculateButton = uibutton(app.LeftPanel, 'push');
            app.CulculateButton.ButtonPushedFcn = createCallbackFcn(app, @CulculateButtonPushed, true);
            app.CulculateButton.Text = 'Culculate';
            app.DeviceSize = uieditfield(app.LeftPanel, 'numeric');
            app.DeviceSize.Limits = [0 Inf];
            app.DeviceSize.ValueDisplayFormat = '%7.2f';
            app.DeviceSize.Value = 400;
            app.BlackbodytempEditFieldLabel = uilabel(app.LeftPanel);
            app.BlackbodytempEditFieldLabel.Text = 'Black body temp (℃)';
            app.BlackbodytempCelcius = uieditfield(app.LeftPanel, 'numeric');
            app.BlackbodytempCelcius.Limits = [0 10000];
            app.BlackbodytempCelcius.ValueDisplayFormat = '%8.f';
            app.BlackbodytempCelcius.ValueChangedFcn = createCallbackFcn(app, @BlackbodytempCelciusValueChanged, true);
            app.BlackbodytempCelcius.Value = 900;
            app.LowedgeumEditFieldLabel = uilabel(app.LeftPanel);
            app.LowedgeumEditFieldLabel.Text = 'Low edge (um)';
            app.Lowedge = uieditfield(app.LeftPanel, 'numeric');
            app.Lowedge.Limits = [0.1 25];
            app.Lowedge.ValueDisplayFormat = '%8.f';
            app.Lowedge.Value = 8;
            app.outputPower = uieditfield(app.LeftPanel, 'numeric');
            app.outputPower.ValueDisplayFormat = '%e';
            app.outputPower.Editable = 'off';
            app.outputCPS = uieditfield(app.LeftPanel, 'numeric');
            app.outputCPS.ValueDisplayFormat = '%e';
            app.outputCPS.Editable = 'off';
            app.HighedgeumEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.HighedgeumEditFieldLabel_2.Text = 'Photon Number (cps)';
            app.HighedgeumEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.HighedgeumEditFieldLabel_3.Text = 'Radiation intensity (W)';
            app.Unit = uilabel(app.LeftPanel);
            app.Unit.Text = 'um^2';
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Radiation Spectrum')
            xlabel(app.PrincipalInterestUIAxes, 'Wavelength (um)')
            ylabel(app.PrincipalInterestUIAxes, 'Spectral Radiant Emission (W m^{−2} μm^{−1})')
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlackbodyRadiationCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end